classdef tilesweep1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        STARTButton       matlab.ui.control.Button
        PRINTSCOREButton  matlab.ui.control.Button
        ENDButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        mat1 = zeros(3);
        mat2 = zeros(3);
        t2 = 0;
        t1 = 0;
        score = 0;
        scoremat = zeros(1,6)
        timermat = zeros(1,6)
        counter = 0
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
          app.Button.BackgroundColor = [0.0 0.0 1.0] 
          app.mat2(1,1) = 1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(1,2) = 1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(1,3) = 1;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(2,1) = 1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(2,2) = 1;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(2,3) = 1;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Button_7.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(3,1) = 1;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Button_8.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(3,2) =1;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Button_9.BackgroundColor = [0.0 0.0 1.0]
            app.mat2(3,3) = 1;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.mat2 = zeros(3);
            app.mat1 = zeros(3);
            app.counter = app.counter + 1;
            flag = 0;
            while flag == 0
            flag = 1
            temp = randi([1,9],1,4)
                for b = 1:3
                  for  c = (b+1):4
                      if temp(1,b) == temp(1,c)
                          flag = 0
                      end
                  end
                end
            end
              if app.counter <= 2
                for k = 1:2
                    if temp(1,k) == 1
                        app.mat1(1,1) = 1;
                    end
                    if temp(1,k) == 2
                        app.mat1(1,2) = 1;
                    end
                    if temp(1,k) == 3
                        app.mat1(1,3) = 1;
                    end
                    if temp(1,k) == 4
                        app.mat1(2,1) = 1;
                    end
                    if temp(1,k) == 5
                        app.mat1(2,2) = 1;
                    end
                    if temp(1,k) == 6
                        app.mat1(2,3) = 1;
                    end
                    if temp(1,k) == 7
                        app.mat1(3,1) = 1;
                    end
                    if temp(1,k) == 8
                        app.mat1(3,2) = 1;
                    end
                    if temp(1,k) == 9
                        app.mat1(3,3) = 1;
                    end
                end
              end
                if app.counter >= 3 & app.counter <=4
                
                for k = 1:3
                    if temp(1,k) == 1
                        app.mat1(1,1) = 1;
                    end
                    if temp(1,k) == 2
                        app.mat1(1,2) = 1;
                    end
                    if temp(1,k) == 3
                        app.mat1(1,3) = 1;
                    end
                    if temp(1,k) == 4
                        app.mat1(2,1) = 1;
                    end
                    if temp(1,k) == 5
                        app.mat1(2,2) = 1;
                    end
                    if temp(1,k) == 6
                        app.mat1(2,3) = 1;
                    end
                    if temp(1,k) == 7
                        app.mat1(3,1) = 1;
                    end
                    if temp(1,k) == 8
                        app.mat1(3,2) = 1;
                    end
                    if temp(1,k) == 9
                        app.mat1(3,3) = 1;
                    end
                end
                end
                if app.counter >= 5 & app.counter <= 6
                
                for k = 1:4
                    if temp(1,k) == 1
                        app.mat1(1,1) = 1;
                    end
                    if temp(1,k) == 2
                        app.mat1(1,2) = 1;
                    end
                    if temp(1,k) == 3
                        app.mat1(1,3) = 1;
                    end
                    if temp(1,k) == 4
                        app.mat1(2,1) = 1;
                    end
                    if temp(1,k) == 5
                        app.mat1(2,2) = 1;
                    end
                    if temp(1,k) == 6
                        app.mat1(2,3) = 1;
                    end
                    if temp(1,k) == 7
                        app.mat1(3,1) = 1;
                    end
                    if temp(1,k) == 8
                        app.mat1(3,2) = 1;
                    end
                    if temp(1,k) == 9
                        app.mat1(3,3) = 1;
                    end
                end
                end
          if app.mat1(1,1) == 1
             app.Button.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(1,2) == 1
             app.Button_2.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(1,3) == 1
             app.Button_3.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(2,1) == 1
             app.Button_4.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(2,2) == 1
             app.Button_5.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(2,3) == 1
             app.Button_6.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(3,1) == 1
             app.Button_7.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(3,2) == 1
             app.Button_8.BackgroundColor = [0.0 0.0 1.0] 
          end
          if app.mat1(3,3) == 1
             app.Button_9.BackgroundColor = [0.0 0.0 1.0] 
          end
          pause(1)
          app.Button.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_2.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_3.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_4.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_5.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_6.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_7.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_8.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_9.BackgroundColor = [0.9412 0.9412 0.9412]
          tic;
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            app.t2 = toc;
            temp = 0
            for p = 1:3
                for q = 1:3
                    if app.mat1(p,q)==app.mat2(p,q)
                      temp = temp +1;
                    end
                end
            end
            num = app.counter
            app.timermat(1,num) = app.t2;
            app.score = 1/app.t2;
            app.score = 10*(temp/9)*app.score;
            app.scoremat(1,num) = app.score
           
          app.Button.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_2.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_3.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_4.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_5.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_6.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_7.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_8.BackgroundColor = [0.9412 0.9412 0.9412]
          app.Button_9.BackgroundColor = [0.9412 0.9412 0.9412]
        end

        % Button pushed function: PRINTSCOREButton
        function PRINTSCOREButtonPushed(app, event)
             T = table(app.scoremat', app.timermat', 'VariableNames', {'SCORE', 'TIME'})
            writetable(T, 'scores.xls')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PRINTSCOREButton = uibutton(app.UIFigure, 'push');
            app.PRINTSCOREButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTSCOREButtonPushed, true);
            app.PRINTSCOREButton.Text = 'PRINT SCORE';
            app.ENDButton = uibutton(app.UIFigure, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = 'END';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tilesweep1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end